classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FuzzyDiscoUIFigure             matlab.ui.Figure
        UIAxes                         matlab.ui.control.UIAxes
        VALUES_PER_HOUREditFieldLabel  matlab.ui.control.Label
        VALUES_PER_HOUREditField       matlab.ui.control.NumericEditField
        CPU1LampLabel                  matlab.ui.control.Label
        CPU1Lamp                       matlab.ui.control.Lamp
        CPU2LampLabel                  matlab.ui.control.Label
        CPU2Lamp                       matlab.ui.control.Lamp
        CPU3LampLabel                  matlab.ui.control.Label
        CPU3Lamp                       matlab.ui.control.Lamp
        ENTERButton                    matlab.ui.control.Button
        CycleCountEditFieldLabel       matlab.ui.control.Label
        CycleCountEditField            matlab.ui.control.NumericEditField
        CPU_distribution1EditFieldLabel  matlab.ui.control.Label
        CPU_distribution1EditField     matlab.ui.control.NumericEditField
        CPU_distribution2EditFieldLabel  matlab.ui.control.Label
        CPU_distribution2EditField     matlab.ui.control.NumericEditField
        CPU_distribution3EditFieldLabel  matlab.ui.control.Label
        CPU_distribution3EditField     matlab.ui.control.NumericEditField
        CPUs12EditFieldLabel           matlab.ui.control.Label
        CPUs12EditField                matlab.ui.control.NumericEditField
        UITable                        matlab.ui.control.Table
        QueueLabel                     matlab.ui.control.Label
        CPUs22EditFieldLabel           matlab.ui.control.Label
        CPUs22EditField                matlab.ui.control.NumericEditField
        CPUs32EditFieldLabel           matlab.ui.control.Label
        CPUs32EditField                matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        VALUES_PER_HOUR;
        Q = int16.empty;
        traffic_sensitivity = 2;
        CPUs = zeros(3,3);
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Define startup lamp color (grey)
            app.CPU1Lamp.Color = [0.80,0.80,0.80];
            app.CPU2Lamp.Color = [0.80,0.80,0.80];
            app.CPU3Lamp.Color = [0.80,0.80,0.80];
        end

        % Value changed function: VALUES_PER_HOUREditField
        function VALUES_PER_HOUREditFieldValueChanged(app, event)
            %input Value per hour
            app.VALUES_PER_HOUR = app.VALUES_PER_HOUREditField.Value;
            
        end

        % Callback function: CycleCountEditField, ENTERButton, UITable
        function ENTERButtonPushed(app, event)
            %main function
            
            [Distribution, Frequency] = generate (25,app.VALUES_PER_HOUR);
            CPUs(1,3) = 0.07;
            CPUs(2,3) = 0.11;
            CPUs(3,3) = 0.15;
            
            %plot Distribution on GUI
            plot ( app.UIAxes, Frequency, Distribution);
            
            i=1;
            
            for Cycle_Counter = 1:1:(app.VALUES_PER_HOUR*24)
                
                package_amount = Distribution(Cycle_Counter);
                
                %package_amount = 2;
                
                app.Q = age(app.Q);
                
                if CPUs(1,2) ~= 0
                    CPUs(1,2) = CPUs(1,2) - 1;
                    if CPUs(1,2) <= 0
                        CPUs(1,2) = 0;
                        CPUs(1,1) = 0;
                    end
                end
                if CPUs(2,2) ~= 0
                    CPUs(2,2) = CPUs(2,2) - 1;
                    if CPUs(2,2) <= 0
                        CPUs(2,2) = 0;
                        CPUs(2,1) = 0;
                    end
                end
                if CPUs(3,2) ~= 0
                    CPUs(3,2) = CPUs(3,2) - 1;
                    if CPUs(3,2) <= 0
                        CPUs(3,2) = 0;
                        CPUs(3,1) = 0;
                    end
                end
                
                for i = 1:(floor(package_amount/3))
                    app.Q = [app.Q 3];
                end
                
                for i = 1:(ceil(package_amount/3))
                    app.Q = [app.Q 7];
                end
                
                for i = 1:(package_amount-(floor(package_amount/3))-(ceil(package_amount/3)))
                    app.Q = [app.Q 15];
                end
                
                
                app.Q = sort(app.Q);
                i = 1;
                while true
                    
                    if app.Q(i) < 1
                        app.Q(i) = [];
                    else
                        break
                    end
                    i = i + 1;
                end
                %i Packages have been dropped
                i = 1;
                
                %expected_traffic = assumption (Cycle_Counter); /change to prediction
                
                expected_traffic = package_amount;
                
                traffic_change = expected_traffic-package_amount;
                
                if traffic_change > app.traffic_sensitivity
                    processing_profile = 0;
                elseif traffic_change < -app.traffic_sensitivity
                    processing_profile = 2;
                else
                    processing_profile = 1;
                end
                
                [CPU_distribution] = process (app.Q, processing_profile, CPUs);
                
                CPUs(1,1) = 1;
                CPUs(2,1) = 1;
                CPUs(3,1) = 1;
                CPUs(1,2) = CPUs(1,2) + CPU_distribution(1)*CPUs(1,3);
                CPUs(2,2) = CPUs(2,2) + CPU_distribution(2)*CPUs(2,3);
                CPUs(3,2) = CPUs(3,2) + CPU_distribution(3)*CPUs(3,3);
                
                p_processed = CPU_distribution(1) + CPU_distribution(2) + CPU_distribution(3);
                
                %Set up color for each CPU when it's working
                if  CPU_distribution(1) <= 0 && CPUs(1,2) == 0
                    app.CPU1Lamp.Color = 'Green';
                elseif CPU_distribution(1) == 0 && CPUs(1,2) > 0
                    app.CPU1Lamp.Color = [1 0.7 0.2];
                else
                    app.CPU1Lamp.Color = 'Red';    
                end
                
                if  CPU_distribution(2) <= 0 && CPUs(2,2) == 0
                    app.CPU2Lamp.Color = 'Green';
                 elseif CPU_distribution(2) == 0 && CPUs(2,2) > 0
                    app.CPU2Lamp.Color = [1 0.7 0.2];
                else
                    app.CPU2Lamp.Color = 'Red';    
                end
                
                if  CPU_distribution(3) <= 0 && CPUs(3,2) == 0
                    app.CPU3Lamp.Color = 'Green';
                elseif CPU_distribution(3) == 0 && CPUs(3,2) > 0
                    app.CPU3Lamp.Color = [1 0.7 0.2];
                else
                    app.CPU3Lamp.Color = 'Red';    
                end
                
                disp (app.Q);
                disp (CPU_distribution(1));
                disp (CPUs(1,2));
                disp (CPU_distribution(2));
                disp (CPU_distribution(3));
                disp end_of_cycle;
                disp (Cycle_Counter);
                
                disp CPU;
                disp (CPUs);
                
                %Show number of cycle on GUI
                app.CycleCountEditField.Value = Cycle_Counter;
                
                %Show CPU_Distribution on GUI
                app.CPU_distribution1EditField.Value = (CPU_distribution(1));
                app.CPU_distribution2EditField.Value = (CPU_distribution(2));
                app.CPU_distribution3EditField.Value = (CPU_distribution(3));
                
                %Show CPUs(i,2) on GUI
                app.CPUs12EditField.Value = (CPUs(1,2));
                app.CPUs22EditField.Value = (CPUs(2,2));
                app.CPUs32EditField.Value = (CPUs(3,2));
               
                %Displauy Q
                app.UITable.Data = app.Q;                
                
                %if p_processed ~= 0
                app.Q = app.Q(1+p_processed:1:end);
                %end
                
                %pause 0.3 sec for displaying GUI each cycle 
                pause(0.3);
            end
            

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FuzzyDiscoUIFigure = uifigure('Visible', 'off');
            app.FuzzyDiscoUIFigure.Name = 'Fuzzy-Disco';
            app.UIAxes = uiaxes(app.FuzzyDiscoUIFigure);
            title(app.UIAxes, 'Distribution')
            xlabel(app.UIAxes, 'time')
            ylabel(app.UIAxes, 'FINALSIGNAL')
            app.VALUES_PER_HOUREditFieldLabel = uilabel(app.FuzzyDiscoUIFigure);
            app.VALUES_PER_HOUREditFieldLabel.Text = 'VALUES_PER_HOUR';
            app.VALUES_PER_HOUREditField = uieditfield(app.FuzzyDiscoUIFigure, 'numeric');
            app.VALUES_PER_HOUREditField.Limits = [0 Inf];
            app.VALUES_PER_HOUREditField.ValueChangedFcn = createCallbackFcn(app, @VALUES_PER_HOUREditFieldValueChanged, true);
            app.CPU1LampLabel = uilabel(app.FuzzyDiscoUIFigure);
            app.CPU1LampLabel.Text = 'CPU 1';
            app.CPU1Lamp = uilamp(app.FuzzyDiscoUIFigure);
            app.CPU2LampLabel = uilabel(app.FuzzyDiscoUIFigure);
            app.CPU2LampLabel.Text = 'CPU 2';
            app.CPU2Lamp = uilamp(app.FuzzyDiscoUIFigure);
            app.CPU3LampLabel = uilabel(app.FuzzyDiscoUIFigure);
            app.CPU3LampLabel.Text = 'CPU 3';
            app.CPU3Lamp = uilamp(app.FuzzyDiscoUIFigure);
            app.ENTERButton = uibutton(app.FuzzyDiscoUIFigure, 'push');
            app.ENTERButton.ButtonPushedFcn = createCallbackFcn(app, @ENTERButtonPushed, true);
            app.ENTERButton.Text = 'ENTER';
            app.CycleCountEditFieldLabel = uilabel(app.FuzzyDiscoUIFigure);
            app.CycleCountEditFieldLabel.Text = 'Cycle Count';
            app.CycleCountEditField = uieditfield(app.FuzzyDiscoUIFigure, 'numeric');
            app.CycleCountEditField.ValueChangedFcn = createCallbackFcn(app, @ENTERButtonPushed, true);
            app.CPU_distribution1EditFieldLabel = uilabel(app.FuzzyDiscoUIFigure);
            app.CPU_distribution1EditFieldLabel.Text = 'CPU_distribution(1)';
            app.CPU_distribution1EditField = uieditfield(app.FuzzyDiscoUIFigure, 'numeric');
            app.CPU_distribution2EditFieldLabel = uilabel(app.FuzzyDiscoUIFigure);
            app.CPU_distribution2EditFieldLabel.Text = 'CPU_distribution(2)';
            app.CPU_distribution2EditField = uieditfield(app.FuzzyDiscoUIFigure, 'numeric');
            app.CPU_distribution3EditFieldLabel = uilabel(app.FuzzyDiscoUIFigure);
            app.CPU_distribution3EditFieldLabel.Text = 'CPU_distribution(3)';
            app.CPU_distribution3EditField = uieditfield(app.FuzzyDiscoUIFigure, 'numeric');
            app.CPUs12EditFieldLabel = uilabel(app.FuzzyDiscoUIFigure);
            app.CPUs12EditFieldLabel.Text = 'CPUs(1,2)';
            app.CPUs12EditField = uieditfield(app.FuzzyDiscoUIFigure, 'numeric');
            app.UITable = uitable(app.FuzzyDiscoUIFigure);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {''};
            app.UITable.CellEditCallback = createCallbackFcn(app, @ENTERButtonPushed, true);
            app.QueueLabel = uilabel(app.FuzzyDiscoUIFigure);
            app.QueueLabel.Text = 'Queue';
            app.CPUs22EditFieldLabel = uilabel(app.FuzzyDiscoUIFigure);
            app.CPUs22EditFieldLabel.Text = 'CPUs(2,2)';
            app.CPUs22EditField = uieditfield(app.FuzzyDiscoUIFigure, 'numeric');
            app.CPUs32EditFieldLabel = uilabel(app.FuzzyDiscoUIFigure);
            app.CPUs32EditFieldLabel.Text = 'CPUs(3,2)';
            app.CPUs32EditField = uieditfield(app.FuzzyDiscoUIFigure, 'numeric');
            % Show the figure after all components are created
            app.FuzzyDiscoUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FuzzyDiscoUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FuzzyDiscoUIFigure)
        end
    end
end