classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Menu                          matlab.ui.container.Menu
        SettingsMenu                  matlab.ui.container.Menu
        LoadMenu                      matlab.ui.container.Menu
        DistributionMenu_3            matlab.ui.container.Menu
        PredictionMenu_3              matlab.ui.container.Menu
        SaveMenu                      matlab.ui.container.Menu
        DistributionMenu_2            matlab.ui.container.Menu
        PredictionMenu_2              matlab.ui.container.Menu
        GenerateMenu                  matlab.ui.container.Menu
        DistributionMenu              matlab.ui.container.Menu
        PredictionMenu                matlab.ui.container.Menu
        AboutMenu                     matlab.ui.container.Menu
        QuitMenu                      matlab.ui.container.Menu
        TabGroup                      matlab.ui.container.TabGroup
        SimulationTab                 matlab.ui.container.Tab
        DistributionPanel             matlab.ui.container.Panel
        UIAxes                        matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        ControlPanel                  matlab.ui.container.Panel
        StartButton                   matlab.ui.control.Button
        PauseButton                   matlab.ui.control.Button
        ResetButton                   matlab.ui.control.Button
        StatusLampLabel               matlab.ui.control.Label
        StatusLamp                    matlab.ui.control.Lamp
        ClusterPanel                  matlab.ui.container.Panel
        UITable                       matlab.ui.control.Table
        UIAxes3                       matlab.ui.control.UIAxes
        LoadGaugeLabel                matlab.ui.control.Label
        LoadGauge                     matlab.ui.control.Gauge
        ModeEditFieldLabel            matlab.ui.control.Label
        ModeEditField                 matlab.ui.control.EditField
        Lamp                          matlab.ui.control.Lamp
        QueuePanel                    matlab.ui.container.Panel
        UIAxes4                       matlab.ui.control.UIAxes
        DroppedpacketsEditFieldLabel  matlab.ui.control.Label
        DroppedpacketsEditField       matlab.ui.control.NumericEditField
        QueuesizeEditFieldLabel       matlab.ui.control.Label
        QueuesizeEditField            matlab.ui.control.NumericEditField
        Image2                        matlab.ui.control.Image
        AuthorsTextArea               matlab.ui.control.TextArea
        StatePanel                    matlab.ui.container.Panel
        CycleEditFieldLabel           matlab.ui.control.Label
        CycleEditField                matlab.ui.control.NumericEditField
        SettingsTab                   matlab.ui.container.Tab
        DistributionPanel_2           matlab.ui.container.Panel
        CyclesEditFieldLabel          matlab.ui.control.Label
        CyclesEditField               matlab.ui.control.NumericEditField
        ScalingEditFieldLabel         matlab.ui.control.Label
        ScalingEditField              matlab.ui.control.NumericEditField
        NoiseEditFieldLabel           matlab.ui.control.Label
        NoiseEditField                matlab.ui.control.NumericEditField
        ApplyButton                   matlab.ui.control.Button
        PackagesPanel                 matlab.ui.container.Panel
        UITable2                      matlab.ui.control.Table
        ClusterPanel_2                matlab.ui.container.Panel
        CPUsSpinnerLabel              matlab.ui.control.Label
        CPUsSpinner                   matlab.ui.control.Spinner
        AdaptivebehaviourCheckBox     matlab.ui.control.CheckBox
        DistributiongeneratorPanel    matlab.ui.container.Panel
        LogTab                        matlab.ui.container.Tab
        TextArea                      matlab.ui.control.TextArea
        ExportButton                  matlab.ui.control.Button
        ClearButton                   matlab.ui.control.Button
    end

    
    properties (Access = public)
        simulation % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.StatusLamp.Color = 'red';
            app.simulation = Simulation(app);
            % Draw initial state of cluster
            app.simulation.cluster.draw();
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            message =  {'Institutsprojekt at RWTH Aachen';...
                        'Supervision: Zamani, Alireza; Zhu, Yao';...
                        'Students: Parfene, Ovidiu-Alexandru; Feng, Yong; Qin, Minxuan; Vasilev, Artem';
                        'Chawalaworn, Chatchon.; Benfer, Romin Niklas'};
            msgbox(message,'About');
        end

        % Menu selected function: QuitMenu
        function QuitMenuSelected(app, event)
            app.delete();
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.StatusLamp.Color = 'green';
            app.simulation.start();
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            app.StatusLamp.Color = 'blue';
            app.simulation.pause();
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.StatusLamp.Color = 'red';
            app.simulation.reset();
            app.simulation.reset();
        end

        % Menu selected function: DistributionMenu
        function DistributionMenuSelected(app, event)
            app.simulation.generateDistribution();
        end

        % Menu selected function: PredictionMenu
        function PredictionMenuSelected(app, event)
            app.simulation.generatePrediction();
        end

        % Menu selected function: DistributionMenu_3
        function DistributionMenu_3Selected(app, event)
            [file,path] = uigetfile();
            if(isequal(file,0))
                % User selected Cancel
            else
                app.simulation.distribution.load(fullfile(path,file));
                app.simulation.distribution.plot(app.UIAxes);
            end
        end

        % Menu selected function: PredictionMenu_3
        function PredictionMenu_3Selected(app, event)
            [file,path] = uigetfile();
            if(isequal(file,0))
                % User selected Cancel
            else
                app.simulation.prediction.load(fullfile(path,file));
                app.simulation.prediction.plot(app.UIAxes2);
            end
        end

        % Menu selected function: DistributionMenu_2
        function DistributionMenu_2Selected(app, event)
            [file,path] = uiputfile();
            if(isequal(file,0) || isequal(path,0))
                % User selected Cancel
            else
                app.simulation.distribution.save(fullfile(path,file));
            end
        end

        % Menu selected function: PredictionMenu_2
        function PredictionMenu_2Selected(app, event)
            [file,path] = uiputfile();
            if(isequal(file,0) || isequal(path,0))
                % User selected Cancel
            else
                app.simulation.prediction.save(fullfile(path,file));
            end
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            app.simulation.cluster.setCpuSpeed(indices(1),newData);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.SettingsMenu = uimenu(app.Menu);
            app.SettingsMenu.Text = 'Settings';
            app.LoadMenu = uimenu(app.Menu);
            app.LoadMenu.Text = 'Load';
            app.DistributionMenu_3 = uimenu(app.LoadMenu);
            app.DistributionMenu_3.MenuSelectedFcn = createCallbackFcn(app, @DistributionMenu_3Selected, true);
            app.DistributionMenu_3.Text = 'Distribution';
            app.PredictionMenu_3 = uimenu(app.LoadMenu);
            app.PredictionMenu_3.MenuSelectedFcn = createCallbackFcn(app, @PredictionMenu_3Selected, true);
            app.PredictionMenu_3.Text = 'Prediction';
            app.SaveMenu = uimenu(app.Menu);
            app.SaveMenu.Text = 'Save';
            app.DistributionMenu_2 = uimenu(app.SaveMenu);
            app.DistributionMenu_2.MenuSelectedFcn = createCallbackFcn(app, @DistributionMenu_2Selected, true);
            app.DistributionMenu_2.Text = 'Distribution';
            app.PredictionMenu_2 = uimenu(app.SaveMenu);
            app.PredictionMenu_2.MenuSelectedFcn = createCallbackFcn(app, @PredictionMenu_2Selected, true);
            app.PredictionMenu_2.Text = 'Prediction';
            app.GenerateMenu = uimenu(app.Menu);
            app.GenerateMenu.Text = 'Generate';
            app.DistributionMenu = uimenu(app.GenerateMenu);
            app.DistributionMenu.MenuSelectedFcn = createCallbackFcn(app, @DistributionMenuSelected, true);
            app.DistributionMenu.Text = 'Distribution';
            app.PredictionMenu = uimenu(app.GenerateMenu);
            app.PredictionMenu.MenuSelectedFcn = createCallbackFcn(app, @PredictionMenuSelected, true);
            app.PredictionMenu.Text = 'Prediction';
            app.AboutMenu = uimenu(app.Menu);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.QuitMenu = uimenu(app.Menu);
            app.QuitMenu.MenuSelectedFcn = createCallbackFcn(app, @QuitMenuSelected, true);
            app.QuitMenu.Text = 'Quit';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SimulationTab = uitab(app.TabGroup);
            app.SimulationTab.Title = 'Simulation';
            app.DistributionPanel = uipanel(app.SimulationTab);
            app.DistributionPanel.Title = 'Distribution';
            app.UIAxes = uiaxes(app.DistributionPanel);
            title(app.UIAxes, 'Package distribution')
            xlabel(app.UIAxes, 'cycle')
            ylabel(app.UIAxes, '# of packets')
            app.UIAxes.XLim = [0 24];
            app.UIAxes2 = uiaxes(app.DistributionPanel);
            title(app.UIAxes2, 'Prediction')
            xlabel(app.UIAxes2, 'cycle')
            ylabel(app.UIAxes2, '# of packets')
            app.UIAxes2.XLim = [0 24];
            app.ControlPanel = uipanel(app.SimulationTab);
            app.ControlPanel.Title = 'Control';
            app.StartButton = uibutton(app.ControlPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.PauseButton = uibutton(app.ControlPanel, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.ResetButton = uibutton(app.ControlPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.StatusLampLabel = uilabel(app.ControlPanel);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.ControlPanel);
            app.ClusterPanel = uipanel(app.SimulationTab);
            app.ClusterPanel.Title = 'Cluster';
            app.UITable = uitable(app.ClusterPanel);
            app.UITable.ColumnName = {'CPU'; 'Status'; 'PPC'; 'Packets'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false false true false];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UIAxes3 = uiaxes(app.ClusterPanel);
            title(app.UIAxes3, 'CPU Usage')
            xlabel(app.UIAxes3, 'cycle')
            ylabel(app.UIAxes3, '# packages assigned')
            app.UIAxes3.XLim = [0 24];
            app.LoadGaugeLabel = uilabel(app.ClusterPanel);
            app.LoadGaugeLabel.Text = 'Load';
            app.LoadGauge = uigauge(app.ClusterPanel, 'circular');
            app.ModeEditFieldLabel = uilabel(app.ClusterPanel);
            app.ModeEditFieldLabel.Text = 'Mode';
            app.ModeEditField = uieditfield(app.ClusterPanel, 'text');
            app.ModeEditField.Editable = 'off';
            app.Lamp = uilamp(app.ClusterPanel);
            app.QueuePanel = uipanel(app.SimulationTab);
            app.QueuePanel.Title = 'Queue';
            app.UIAxes4 = uiaxes(app.QueuePanel);
            title(app.UIAxes4, 'Queue')
            xlabel(app.UIAxes4, 'cycle')
            ylabel(app.UIAxes4, '# of packets')
            app.UIAxes4.XLim = [0 24];
            app.DroppedpacketsEditFieldLabel = uilabel(app.QueuePanel);
            app.DroppedpacketsEditFieldLabel.Text = 'Dropped packets';
            app.DroppedpacketsEditField = uieditfield(app.QueuePanel, 'numeric');
            app.DroppedpacketsEditField.Editable = 'off';
            app.QueuesizeEditFieldLabel = uilabel(app.QueuePanel);
            app.QueuesizeEditFieldLabel.Text = 'Queue size';
            app.QueuesizeEditField = uieditfield(app.QueuePanel, 'numeric');
            app.QueuesizeEditField.Editable = 'off';
            app.Image2 = uiimage(app.SimulationTab);
            app.Image2.ImageSource = 'rwth_ti_bild_rgb.png';
            app.AuthorsTextArea = uitextarea(app.SimulationTab);
            app.AuthorsTextArea.Editable = 'off';
            app.AuthorsTextArea.Value = {'Supervision:  Zamani, Alireza; Zhu, Yao'; 'Students:       Parfene, Ovidiu-Alexandru; Feng, Yong; Qin, Minxuan; Vasilev, Artem;'; '                      Chawalaworn, Chatchon.; Benfer, Romin Niklas'};
            app.StatePanel = uipanel(app.SimulationTab);
            app.StatePanel.Title = 'State';
            app.CycleEditFieldLabel = uilabel(app.StatePanel);
            app.CycleEditFieldLabel.Text = 'Cycle';
            app.CycleEditField = uieditfield(app.StatePanel, 'numeric');
            app.CycleEditField.Editable = 'off';
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.DistributionPanel_2 = uipanel(app.SettingsTab);
            app.DistributionPanel_2.Title = 'Distribution';
            app.CyclesEditFieldLabel = uilabel(app.DistributionPanel_2);
            app.CyclesEditFieldLabel.Text = 'Cycles';
            app.CyclesEditField = uieditfield(app.DistributionPanel_2, 'numeric');
            app.ScalingEditFieldLabel = uilabel(app.DistributionPanel_2);
            app.ScalingEditFieldLabel.Text = 'Scaling';
            app.ScalingEditField = uieditfield(app.DistributionPanel_2, 'numeric');
            app.NoiseEditFieldLabel = uilabel(app.DistributionPanel_2);
            app.NoiseEditFieldLabel.Text = 'Noise';
            app.NoiseEditField = uieditfield(app.DistributionPanel_2, 'numeric');
            app.ApplyButton = uibutton(app.SettingsTab, 'push');
            app.ApplyButton.Text = 'Apply';
            app.PackagesPanel = uipanel(app.SettingsTab);
            app.PackagesPanel.Title = 'Packages';
            app.UITable2 = uitable(app.PackagesPanel);
            app.UITable2.ColumnName = {'Class'; 'probability (%)'; 'urgency min'; 'urgency max'};
            app.UITable2.RowName = {};
            app.ClusterPanel_2 = uipanel(app.SettingsTab);
            app.ClusterPanel_2.Title = 'Cluster';
            app.CPUsSpinnerLabel = uilabel(app.ClusterPanel_2);
            app.CPUsSpinnerLabel.Text = 'CPUs';
            app.CPUsSpinner = uispinner(app.ClusterPanel_2);
            app.AdaptivebehaviourCheckBox = uicheckbox(app.ClusterPanel_2);
            app.AdaptivebehaviourCheckBox.Text = 'Adaptive behaviour';
            app.DistributiongeneratorPanel = uipanel(app.SettingsTab);
            app.DistributiongeneratorPanel.Title = 'Distribution generator';
            app.LogTab = uitab(app.TabGroup);
            app.LogTab.Title = 'Log';
            app.TextArea = uitextarea(app.LogTab);
            app.ExportButton = uibutton(app.LogTab, 'push');
            app.ExportButton.Text = 'Export';
            app.ClearButton = uibutton(app.LogTab, 'push');
            app.ClearButton.Text = 'Clear';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end