classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Menu                       matlab.ui.container.Menu
        LoadMenu                   matlab.ui.container.Menu
        SettingsMenu               matlab.ui.container.Menu
        DistributionMenu           matlab.ui.container.Menu
        PredictionMenu             matlab.ui.container.Menu
        SaveMenu                   matlab.ui.container.Menu
        SettingsMenu_2             matlab.ui.container.Menu
        DistributionMenu_2         matlab.ui.container.Menu
        PredictionMenu_2           matlab.ui.container.Menu
        GenerateMenu               matlab.ui.container.Menu
        DistributionMenu_3         matlab.ui.container.Menu
        PredictionMenu_3           matlab.ui.container.Menu
        TabGroup                   matlab.ui.container.TabGroup
        SimulationTab              matlab.ui.container.Tab
        DistributionPanel          matlab.ui.container.Panel
        UIAxes                     matlab.ui.control.UIAxes
        UIAxes2                    matlab.ui.control.UIAxes
        ClusterPanel               matlab.ui.container.Panel
        UIAxes3                    matlab.ui.control.UIAxes
        UITable                    matlab.ui.control.Table
        QueuesizeGaugeLabel        matlab.ui.control.Label
        QueuesizeGauge             matlab.ui.control.LinearGauge
        DroppedEditFieldLabel      matlab.ui.control.Label
        DroppedEditField           matlab.ui.control.NumericEditField
        Gauge                      matlab.ui.control.Gauge
        ClusterloadLabel           matlab.ui.control.Label
        CycleEditFieldLabel        matlab.ui.control.Label
        CycleEditField             matlab.ui.control.NumericEditField
        ControlPanel               matlab.ui.container.Panel
        VariationKnobLabel         matlab.ui.control.Label
        VariationKnob              matlab.ui.control.Knob
        CPUsSpinnerLabel           matlab.ui.control.Label
        CPUsSpinner                matlab.ui.control.Spinner
        CyclesEditFieldLabel       matlab.ui.control.Label
        CyclesEditField            matlab.ui.control.NumericEditField
        ApplyButton_2              matlab.ui.control.Button
        behaviourSliderLabel       matlab.ui.control.Label
        behaviourSlider            matlab.ui.control.Slider
        AdaptivebehaviourCheckBox  matlab.ui.control.CheckBox
        Image                      matlab.ui.control.Image
        IntervalmsEditFieldLabel   matlab.ui.control.Label
        IntervalmsEditField        matlab.ui.control.NumericEditField
        Panel                      matlab.ui.container.Panel
        StartButton                matlab.ui.control.Button
        PauseButton                matlab.ui.control.Button
        ResetButton                matlab.ui.control.Button
        StatusLampLabel            matlab.ui.control.Label
        StatusLamp                 matlab.ui.control.Lamp
        LogTab                     matlab.ui.container.Tab
        LogTextAreaLabel           matlab.ui.control.Label
        LogTextArea                matlab.ui.control.TextArea
        ClearButton                matlab.ui.control.Button
        ExportButton               matlab.ui.control.Button
    end

    
    properties (Access = private)
        cluster % Description
        distribution % Description
        history
        clock % Cycle counter
        
        interval
        
        setting_cycles
        setting_cpus
        setting_interval
        setting_adaptive_behaviour
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        
            % Reset
            app.ResetButtonPushed();
            
            % Initialize defaults
            
            app.setting_interval = 0;
            
            app.distribution = Distribution(24);
            app.history = History(24);
            app.cluster = Cluster();
            app.interval = 0;
        end

        % Value changed function: CPUsSpinner
        function CPUsSpinnerValueChanged(app, event)
            value = app.CPUsSpinner.Value;
            app.setting_cpus = value;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.StatusLamp.Color = 'green';
            
            for i = 1:1:(24)
                app.clock = i;
                app.CycleEditField.Value = app.clock;
                num_packets = app.distribution.at(i);
                app.history.record(i,num_packets);
                
                for k = 1:1:num_packets
                    app.cluster.addPacket(10);
                end
                
                pause(app.interval*0.001);
                %app.cluster.update();
            end
            
            app.history.adjust();
            plot(app.UIAxes2, app.history.s_predicted.time, app.history.s_predicted.field);
            
            app.StatusLamp.Color = 'red';
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            app.StatusLamp.Color = 'blue';
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.StatusLamp.Color = 'red';
            app.clock = 0;
        end

        % Button pushed function: ApplyButton_2
        function ApplyButton_2Pushed(app, event)
            app.cluster.setCpuCount(app.setting_cpus);
            app.interval = app.setting_interval;
        end

        % Menu selected function: SettingsMenu
        function SettingsMenuSelected(app, event)
            [file,path] = uigetfile();
            if isequal(file,0)
                disp('User selected Cancel');
            else
                disp(['User selected ', fullfile(path,file)]);
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Value changed function: CyclesEditField
        function CyclesEditFieldValueChanged(app, event)
            value = app.CyclesEditField.Value;
            app.setting_cycles = value;
        end

        % Display data changed function: UITable
        function UITableDisplayDataChanged(app, event)
            newDisplayData = app.UITable.DisplayData;
        end

        % Menu selected function: DistributionMenu_3
        function DistributionMenu_3Selected(app, event)
            app.distribution.generate(200);
            plot(app.UIAxes, app.distribution.time, app.distribution.field);
        end

        % Menu selected function: PredictionMenu_3
        function PredictionMenu_3Selected(app, event)
            app.history.s_predicted.generate(500);
            plot(app.UIAxes2, app.history.s_predicted.time, app.history.s_predicted.field);
        end

        % Value changed function: IntervalmsEditField
        function IntervalmsEditFieldValueChanged(app, event)
            value = app.IntervalmsEditField.Value;
            app.setting_interval = value;
        end

        % Value changed function: CycleEditField
        function CycleEditFieldValueChanged(app, event)
            value = app.CycleEditField.Value;
            
        end

        % Menu selected function: DistributionMenu
        function DistributionMenuSelected(app, event)
            [file,path] = uigetfile();
            if isequal(file,0)
                disp('User selected Cancel');
            else
                disp(['User selected ', fullfile(path,file)]);
            end
        end

        % Menu selected function: PredictionMenu
        function PredictionMenuSelected(app, event)
            [file,path] = uigetfile();
            if isequal(file,0)
                disp('User selected Cancel');
            else
                disp(['User selected ', fullfile(path,file)]);
            end
        end

        % Menu selected function: DistributionMenu_2
        function DistributionMenu_2Selected(app, event)
            [file,path] = uiputfile();
            if isequal(file,0) || isequal(path,0)
                disp('User clicked Cancel.')
            else
                
            end
        end

        % Menu selected function: PredictionMenu_2
        function PredictionMenu_2Selected(app, event)
            [file,path] = uiputfile();
            if isequal(file,0) || isequal(path,0)
                disp('User clicked Cancel.')
            else
                
            end            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.LoadMenu = uimenu(app.Menu);
            app.LoadMenu.Text = 'Load';
            app.SettingsMenu = uimenu(app.LoadMenu);
            app.SettingsMenu.MenuSelectedFcn = createCallbackFcn(app, @SettingsMenuSelected, true);
            app.SettingsMenu.Text = 'Settings';
            app.DistributionMenu = uimenu(app.LoadMenu);
            app.DistributionMenu.MenuSelectedFcn = createCallbackFcn(app, @DistributionMenuSelected, true);
            app.DistributionMenu.Text = 'Distribution';
            app.PredictionMenu = uimenu(app.LoadMenu);
            app.PredictionMenu.MenuSelectedFcn = createCallbackFcn(app, @PredictionMenuSelected, true);
            app.PredictionMenu.Text = 'Prediction';
            app.SaveMenu = uimenu(app.Menu);
            app.SaveMenu.Text = 'Save';
            app.SettingsMenu_2 = uimenu(app.SaveMenu);
            app.SettingsMenu_2.Text = 'Settings';
            app.DistributionMenu_2 = uimenu(app.SaveMenu);
            app.DistributionMenu_2.MenuSelectedFcn = createCallbackFcn(app, @DistributionMenu_2Selected, true);
            app.DistributionMenu_2.Text = 'Distribution';
            app.PredictionMenu_2 = uimenu(app.SaveMenu);
            app.PredictionMenu_2.MenuSelectedFcn = createCallbackFcn(app, @PredictionMenu_2Selected, true);
            app.PredictionMenu_2.Text = 'Prediction';
            app.GenerateMenu = uimenu(app.Menu);
            app.GenerateMenu.Text = 'Generate';
            app.DistributionMenu_3 = uimenu(app.GenerateMenu);
            app.DistributionMenu_3.MenuSelectedFcn = createCallbackFcn(app, @DistributionMenu_3Selected, true);
            app.DistributionMenu_3.Text = 'Distribution';
            app.PredictionMenu_3 = uimenu(app.GenerateMenu);
            app.PredictionMenu_3.MenuSelectedFcn = createCallbackFcn(app, @PredictionMenu_3Selected, true);
            app.PredictionMenu_3.Text = 'Prediction';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SimulationTab = uitab(app.TabGroup);
            app.SimulationTab.Title = 'Simulation';
            app.DistributionPanel = uipanel(app.SimulationTab);
            app.DistributionPanel.Title = 'Distribution';
            app.UIAxes = uiaxes(app.DistributionPanel);
            title(app.UIAxes, 'Package distribution')
            xlabel(app.UIAxes, 'cycle')
            ylabel(app.UIAxes, '# of packages')
            app.UIAxes2 = uiaxes(app.DistributionPanel);
            title(app.UIAxes2, 'Prediction')
            xlabel(app.UIAxes2, 'cycle')
            ylabel(app.UIAxes2, '# of packages')
            app.ClusterPanel = uipanel(app.SimulationTab);
            app.ClusterPanel.Title = 'Cluster';
            app.UIAxes3 = uiaxes(app.ClusterPanel);
            title(app.UIAxes3, 'CPU Usage')
            xlabel(app.UIAxes3, 'cycle')
            ylabel(app.UIAxes3, '# of packages assigned')
            app.UITable = uitable(app.ClusterPanel);
            app.UITable.ColumnName = {'CPU'; 'PPC'; 'Status'; 'Packages'};
            app.UITable.RowName = {''};
            app.UITable.ColumnEditable = [false true false false];
            app.UITable.DisplayDataChangedFcn = createCallbackFcn(app, @UITableDisplayDataChanged, true);
            app.QueuesizeGaugeLabel = uilabel(app.ClusterPanel);
            app.QueuesizeGaugeLabel.Text = 'Queue size';
            app.QueuesizeGauge = uigauge(app.ClusterPanel, 'linear');
            app.DroppedEditFieldLabel = uilabel(app.ClusterPanel);
            app.DroppedEditFieldLabel.Text = 'Dropped';
            app.DroppedEditField = uieditfield(app.ClusterPanel, 'numeric');
            app.Gauge = uigauge(app.ClusterPanel, 'circular');
            app.ClusterloadLabel = uilabel(app.ClusterPanel);
            app.ClusterloadLabel.Text = 'Cluster load';
            app.CycleEditFieldLabel = uilabel(app.ClusterPanel);
            app.CycleEditFieldLabel.Text = 'Cycle';
            app.CycleEditField = uieditfield(app.ClusterPanel, 'numeric');
            app.CycleEditField.Limits = [0 Inf];
            app.CycleEditField.ValueChangedFcn = createCallbackFcn(app, @CycleEditFieldValueChanged, true);
            app.CycleEditField.Editable = 'off';
            app.ControlPanel = uipanel(app.SimulationTab);
            app.ControlPanel.Title = 'Control';
            app.VariationKnobLabel = uilabel(app.ControlPanel);
            app.VariationKnobLabel.Text = 'Variation';
            app.VariationKnob = uiknob(app.ControlPanel, 'continuous');
            app.CPUsSpinnerLabel = uilabel(app.ControlPanel);
            app.CPUsSpinnerLabel.Text = 'CPUs';
            app.CPUsSpinner = uispinner(app.ControlPanel);
            app.CPUsSpinner.ValueChangedFcn = createCallbackFcn(app, @CPUsSpinnerValueChanged, true);
            app.CyclesEditFieldLabel = uilabel(app.ControlPanel);
            app.CyclesEditFieldLabel.Text = 'Cycles';
            app.CyclesEditField = uieditfield(app.ControlPanel, 'numeric');
            app.CyclesEditField.ValueChangedFcn = createCallbackFcn(app, @CyclesEditFieldValueChanged, true);
            app.ApplyButton_2 = uibutton(app.ControlPanel, 'push');
            app.ApplyButton_2.ButtonPushedFcn = createCallbackFcn(app, @ApplyButton_2Pushed, true);
            app.ApplyButton_2.Text = 'Apply';
            app.behaviourSliderLabel = uilabel(app.ControlPanel);
            app.behaviourSliderLabel.Text = 'behaviour';
            app.behaviourSlider = uislider(app.ControlPanel);
            app.behaviourSlider.Limits = [0 10];
            app.AdaptivebehaviourCheckBox = uicheckbox(app.ControlPanel);
            app.AdaptivebehaviourCheckBox.Text = 'Adaptive behaviour';
            app.Image = uiimage(app.ControlPanel);
            app.Image.ImageSource = 'rwth_ti_bild_rgb.png';
            app.IntervalmsEditFieldLabel = uilabel(app.ControlPanel);
            app.IntervalmsEditFieldLabel.Text = 'Interval (ms)';
            app.IntervalmsEditField = uieditfield(app.ControlPanel, 'numeric');
            app.IntervalmsEditField.ValueChangedFcn = createCallbackFcn(app, @IntervalmsEditFieldValueChanged, true);
            app.Panel = uipanel(app.ControlPanel);
            app.StartButton = uibutton(app.Panel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.PauseButton = uibutton(app.Panel, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.ResetButton = uibutton(app.Panel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.StatusLampLabel = uilabel(app.Panel);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.Panel);
            app.LogTab = uitab(app.TabGroup);
            app.LogTab.Title = 'Log';
            app.LogTextAreaLabel = uilabel(app.LogTab);
            app.LogTextAreaLabel.Text = 'Log';
            app.LogTextArea = uitextarea(app.LogTab);
            app.ClearButton = uibutton(app.LogTab, 'push');
            app.ClearButton.Text = 'Clear';
            app.ExportButton = uibutton(app.LogTab, 'push');
            app.ExportButton.Text = 'Export';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end